classdef Anotaciones_no_supervisado < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GrillaCheckBox                 matlab.ui.control.CheckBox
        TiempoMostrar                  matlab.ui.control.NumericEditField
        TiempoavisualizarLabel         matlab.ui.control.Label
        CanalavisualizarDropDown       matlab.ui.control.DropDown
        CanalavisualizarDropDownLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        all_channel_features % Estructura para almacenar las características de los canales
        all_channel_clusters % Estructura para almacenar los clusters de los canales
        all_channel_data     % Estructura para almacenar la señal original de los canales
        Fs                   % Frecuencia de muestreo
    end
    
    methods (Access = public)
        
        function actualizarGrafica(app)
        % Obtener el nombre del canal seleccionado en el DropDown
        selectedChannel = app.CanalavisualizarDropDown.Value;
    
        % Verificar que el canal seleccionado tiene datos y clusters
        if isfield(app.all_channel_data, selectedChannel) && isfield(app.all_channel_clusters, selectedChannel)
                % Obtener la señal original del canal
                channelData = app.all_channel_data.(selectedChannel);
                
                % Obtener las etiquetas de clusters
                channelClusters = app.all_channel_clusters.(selectedChannel);
                
                % Parámetros de ventana
                window_size = 30; % Tamaño de la ventana en segundos (ajústalo si es necesario)
                samples_per_window = window_size * app.Fs;
                
                % Número total de muestras y creación del vector de tiempo
                totalSamples = length(channelData);
                timeVector = (0:totalSamples-1) / app.Fs; % Vector de tiempo en segundos
                
                % Número de ventanas
                num_windows = length(channelClusters);
                
                % Limpiar la gráfica
                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
                
                % Definir colores para los clusters
                num_clusters = max(channelClusters);
                clusterColors = lines(num_clusters); % Genera una matriz de colores
                
                % Graficar cada ventana con su color correspondiente
                for w = 1:num_windows
                    % Calcular índices de inicio y fin para cada ventana
                    start_idx = (w-1)*samples_per_window + 1;
                    end_idx = min(w*samples_per_window, totalSamples);
                    
                    % Extraer datos y tiempo de la ventana
                    window_data = channelData(start_idx:end_idx);
                    window_time = timeVector(start_idx:end_idx);
                    
                    % Obtener el color según el cluster
                    cluster_label = channelClusters(w);
                    color = clusterColors(cluster_label, :);
                    
                    % Graficar la ventana
                    plot(app.UIAxes, window_time, window_data, 'Color', color);
                end
                
                % Configurar la gráfica
                title(app.UIAxes, ['Canal: ', selectedChannel]);
                xlabel(app.UIAxes, 'Tiempo (s)');
                ylabel(app.UIAxes, 'Voltaje (\muV)');
                hold(app.UIAxes, 'off');
                
                % Ajustar grilla según el estado del checkbox
                if app.GrillaCheckBox.Value
                    grid(app.UIAxes, 'on');
                else
                    grid(app.UIAxes, 'off');
                end
            else
                % Si el canal no tiene datos, muestra un mensaje
                uialert(app.UIFigure, ['No se encontraron datos para el canal ', selectedChannel], 'Error');
            end
            disp(app.all_channel_data); % Mostrar para verificar que contiene datos
            disp(app.all_channel_features); % Mostrar para verificar que contiene datos
            disp(app.all_channel_clusters); % Mostrar para verificar que contiene datos
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CanalavisualizarDropDown
        function CanalavisualizarDropDownValueChanged(app, event)
            value = app.CanalavisualizarDropDown.Value;
            actualizarGrafica(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal EEG agrupada')
            xlabel(app.UIAxes, 'Tiempo (HH:MM:SS)')
            ylabel(app.UIAxes, 'Voltaje (uV)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTickLabel = {'-300'; '-200'; '-100'; '0'; '100'; '200'; '300'};
            app.CanalavisualizarDropDownLabel = uilabel(app.UIFigure);
            app.CanalavisualizarDropDownLabel.Text = 'Canal a visualizar';
            app.CanalavisualizarDropDown = uidropdown(app.UIFigure);
            app.CanalavisualizarDropDown.Items = {'-'};
            app.CanalavisualizarDropDown.ValueChangedFcn = createCallbackFcn(app, @CanalavisualizarDropDownValueChanged, true);
            app.CanalavisualizarDropDown.Value = '-';
            app.TiempoavisualizarLabel = uilabel(app.UIFigure);
            app.TiempoavisualizarLabel.Text = 'Tiempo a visualizar';
            app.TiempoMostrar = uieditfield(app.UIFigure, 'numeric');
            app.TiempoMostrar.Limits = [1 300];
            app.TiempoMostrar.Value = 60;
            app.GrillaCheckBox = uicheckbox(app.UIFigure);
            app.GrillaCheckBox.Text = 'Grilla';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Anotaciones_no_supervisado

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end