classdef consultas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConsultasUIFigure             matlab.ui.Figure
        NoPruebaEditField             matlab.ui.control.EditField
        NoPruebaEditFieldLabel        matlab.ui.control.Label
        BorrarpruebaButton            matlab.ui.control.Button
        DatosconfidencialesButton     matlab.ui.control.StateButton
        NotasconfidencialesTextArea   matlab.ui.control.TextArea
        NotasconfidencialesTextAreaLabel  matlab.ui.control.Label
        ApellidoEditField             matlab.ui.control.EditField
        ApellidoEditFieldLabel        matlab.ui.control.Label
        NombreEditField               matlab.ui.control.EditField
        NombreEditFieldLabel          matlab.ui.control.Label
        DescargarpruebasPanel         matlab.ui.container.Panel
        SupLabel                      matlab.ui.control.Label
        InfLabel                      matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        SeleccionarnmeroCheckBox      matlab.ui.control.CheckBox
        SeleccionarrangoCheckBox      matlab.ui.control.CheckBox
        EditField_2                   matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        TodoslosarchivosCheckBox      matlab.ui.control.CheckBox
        DescargarButton               matlab.ui.control.Button
        ActualizarButton              matlab.ui.control.Button
        RegresarButton                matlab.ui.control.Button
        NuevaconsultaButton           matlab.ui.control.Button
        BuscarButton                  matlab.ui.control.Button
        AgregarnuevoarchivoPanel      matlab.ui.container.Panel
        NoCanalesEditField            matlab.ui.control.EditField
        NoCanalesEditFieldLabel       matlab.ui.control.Label
        FrecuenciaHzEditField         matlab.ui.control.EditField
        FrecuenciaHzEditFieldLabel    matlab.ui.control.Label
        FechadelexamenEditField       matlab.ui.control.EditField
        FechadelexamenEditFieldLabel  matlab.ui.control.Label
        GuardarButton                 matlab.ui.control.Button
        SeleccionarButton             matlab.ui.control.Button
        DuracinsEditField             matlab.ui.control.EditField
        DuracinsEditFieldLabel        matlab.ui.control.Label
        HoradeinicioEditField         matlab.ui.control.EditField
        HoradeinicioEditFieldLabel    matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        CondicinEditField             matlab.ui.control.EditField
        CondicinEditFieldLabel        matlab.ui.control.Label
        DiagnsticodepreescripcinTextArea  matlab.ui.control.TextArea
        DiagnsticodepreescripcinTextAreaLabel  matlab.ui.control.Label
        AntecedentespersonalespatolgicosTextArea  matlab.ui.control.TextArea
        AntecedentespersonalespatolgicosTextAreaLabel  matlab.ui.control.Label
        FechadeNacimientoEditField    matlab.ui.control.EditField
        FechadeNacimientoEditFieldLabel  matlab.ui.control.Label
        SexoEditField                 matlab.ui.control.EditField
        SexoEditFieldLabel            matlab.ui.control.Label
        IngreseelcdigodePacienteEditField  matlab.ui.control.EditField
        IngreseelcdigodePacienteEditFieldLabel  matlab.ui.control.Label
        ConsultadepacientesypruebasLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        datos
        Conexion
        Estadoc
        user
        cconfidencial
    end
    
    methods (Access = public)
        
        function refreshTabla(app)
            id_paciente = app.IngreseelcdigodePacienteEditField.Value;
            global p;
            p.MyData=[];
            app.UITable.Data = {};

            conn = app.Conexion;

            query = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE id_paciente = "' ...
                id_paciente...
                '"'];
            
            data2 = fetch(conn,query);

            % Mostrar datos en tablas
            dimension=size(data2);
            for i=1:dimension(1)
                prueba = sprintf('%d',data2.id_prueba(i));
                fecha = sprintf('%s',cell2mat(data2.fecha_prueba(i)));
                hora = sprintf('%s',cell2mat(data2.hora_inicio(i)));
                duracion = sprintf('%d',data2.duracion(i));
                frecuencia = sprintf('%d',data2.frecuencia(i));
                canales = sprintf('%d',data2.no_canales(i));
                p.MyData=[p.MyData; [{i} {prueba} {fecha} {hora} {duracion} {frecuencia} {canales}]];
            end
            app.UITable.Data = p.MyData;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con, user)
            movegui(app.ConsultasUIFigure,"center");
            app.CallingApp = mainapp;
            global p
            p.MyData=[];
            app.Conexion = con;
            app.user = user;
            
            query = ['SELECT * ' ...
                'FROM humana.usuarios WHERE usuario= "' ...
                user...
                '"'];
            data = fetch(app.Conexion,query);
            if (data.consulta_confidencial == 1)
                app.DatosconfidencialesButton.Enable = 'on';
                app.cconfidencial = 1;
            else
                app.cconfidencial = 0;
            end
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
%             app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
%             app.CallingApp.ConsultarButton.Enable = 'on';
%             app.CallingApp.AnalizarpruebaButton.Enable = 'on';
%             app.CallingApp.DesconectarButton.Enable = 'on';
            delete(app)
        end

        % Close request function: ConsultasUIFigure
        function ConsultasUIFigureCloseRequest(app, event)
%             app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
%             app.CallingApp.ConsultarButton.Enable = 'on';
%             app.CallingApp.AnalizarpruebaButton.Enable = 'on';
%             app.CallingApp.DesconectarButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            global p
            id_paciente = app.IngreseelcdigodePacienteEditField.Value;
            p.MyData=[];
            app.UITable.Data = p.MyData;
            %------ Conexion con la base de datos------------------------
            conn = app.Conexion;
            if app.cconfidencial == 1
                query = ['SELECT * ' ...
                'FROM humana.pacientes WHERE pacientes.id_paciente= "' ...
                id_paciente...
                '"'];
            else
                query = ['SELECT id_paciente, sexo, fecha_nacimiento, antecedentes, diagnostico, condicion ' ...
                'FROM humana.pacientes WHERE pacientes.id_paciente= "' ...
                id_paciente...
                '"'];
            end
            
            query2 = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"'];
            
            
            data = fetch(conn,query);
            data2 = fetch(conn,query2);
            
            if(isempty(data)==1)
                fig = app.ConsultasUIFigure;
                message = 'Paciente no encontrado';
                uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato 
                ini = char(' ');
                p.MyData=[];
                app.IngreseelcdigodePacienteEditField.Value = ini;
                app.SexoEditField.Value = ini;
                app.FechadeNacimientoEditField.Value = ini;
                app.CondicinEditField.Value = ini;
                app.AntecedentespersonalespatolgicosTextArea.Value = ini;
                app.DiagnsticodepreescripcinTextArea.Value = ini;
                app.UITable.Data = p.MyData;
                
            else
                app.SexoEditField.Value = sprintf('%s',cell2mat(data.sexo));
                app.FechadeNacimientoEditField.Value = sprintf('%s',cell2mat(data.fecha_nacimiento));
                cond = sprintf('%s',cell2mat(data.condicion));
                if strcmp(cond,'SANO')
                    app.CondicinEditField.Value = 'No padece epilepsia';
                else
                    app.CondicinEditField.Value = 'Padece epilepsia';
                end
                app.AntecedentespersonalespatolgicosTextArea.Value = sprintf('%s',cell2mat(data.antecedentes));
                app.DiagnsticodepreescripcinTextArea.Value = sprintf('%s',cell2mat(data.diagnostico));
                if app.cconfidencial == 1
                    app.NotasconfidencialesTextArea.Value = sprintf('%s',cell2mat(data.notas_confidenciales));
                    app.NombreEditField.Value = char(data.nombre);
                    app.ApellidoEditField.Value = char(data.apellido);
                end
                % Mostrar datos en tablas
                dimension=size(data2);
                for i=1:dimension(1)
                    prueba = sprintf('%d',data2.id_prueba(i));
                    fecha = sprintf('%s',cell2mat(data2.fecha_prueba(i)));
                    hora = sprintf('%s',cell2mat(data2.hora_inicio(i)));
                    duracion = sprintf('%d',data2.duracion(i));
                    frecuencia = sprintf('%d',data2.frecuencia(i));
                    canales = sprintf('%d',data2.no_canales(i));
                    p.MyData=[p.MyData; [{i} {prueba} {fecha} {hora} {duracion} {frecuencia} {canales}]];
                end
                app.UITable.Data = p.MyData;
            end
            
            %Clear variables
            clear query query2
        end

        % Button pushed function: NuevaconsultaButton
        function NuevaconsultaButtonPushed(app, event)
            global p    
            ini = char('');
            p.MyData=[];
            app.IngreseelcdigodePacienteEditField.Value = ini;
            app.SexoEditField.Value = ini;
            app.FechadeNacimientoEditField.Value = ini;
            app.CondicinEditField.Value = ini;
            app.AntecedentespersonalespatolgicosTextArea.Value = ini;
            app.DiagnsticodepreescripcinTextArea.Value = ini;
            app.UITable.Data = p.MyData;
            app.HoradeinicioEditField.Value = ini;
            app.DuracinsEditField .Value = ini;
            app.FechadelexamenEditField.Value = ini;
            app.FrecuenciaHzEditField.Value = ini;
            app.NoCanalesEditField.Value = ini;

            app.NotasconfidencialesTextArea.Value = "";
            app.NombreEditField.Value = "";
            app.ApellidoEditField.Value = "";
            
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            global p
            p.MyData=[];
            ini = char('');
            app.HoradeinicioEditField.Value = ini;
            app.DuracinsEditField .Value = ini;
            app.FechadelexamenEditField.Value = ini;
            app.FrecuenciaHzEditField.Value = ini;
            app.NoCanalesEditField.Value = ini;
            app.UITable.Data = p.MyData;
            id_paciente=app.IngreseelcdigodePacienteEditField.Value;
            conn = app.Conexion;
            
            %Set query to execute on the database
            query = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"'];
            
            data = fetch(conn,query);
            
            dimension=size(data);
            for i=1:dimension(1)
                prueba = sprintf('%d',data.id_prueba(i));
                fecha = sprintf('%s',cell2mat(data.fecha_prueba(i)));
                hora = sprintf('%s',cell2mat(data.hora_inicio(i)));
                duracion = sprintf('%d',data.duracion(i));
                freq = sprintf('%d',data.frecuencia(i));
                canales = sprintf('%d',data.no_canales(i));
                p.MyData=[p.MyData; [{i} {prueba} {fecha} {hora} {duracion} {freq} {canales}]];
            end
            app.UITable.Data = p.MyData;
            
            %Clear variables
            clear query
        end

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
          
            [file,path,indx] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            
            [hdr, record] = edfread(strcat(path,file));
            tinicio = sprintf('%s',hdr.starttime);
            finicio = sprintf('%s',hdr.startdate);
            duracions = sprintf('%d',hdr.records);
            frecuencia = sprintf('%d',hdr.frequency(1));
            canales = sprintf('%d',hdr.ns);
            app.HoradeinicioEditField.Value = tinicio;
            app.DuracinsEditField.Value = duracions;
            app.FechadelexamenEditField.Value = finicio;
            app.FrecuenciaHzEditField.Value = frecuencia;
            app.NoCanalesEditField.Value = canales;
            serializedData = serialize(record);
            
            app.datos=record;
            
            % Guardar los vectores del archivo original en archivo.bin
            fid = fopen('archivo.bin', 'w');
            fwrite(fid, serializedData, '*uint8');
            fclose(fid);

            %Habilitar botón guardar
            app.GuardarButton.Enable = "on";
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            id_paciente = app.IngreseelcdigodePacienteEditField.Value;
            tinicio = app.HoradeinicioEditField.Value;
            duracions = str2double(app.DuracinsEditField.Value);
            finicio = app.FechadelexamenEditField.Value;
            frecuencia = str2double(app.FrecuenciaHzEditField.Value);
            canales = str2double(app.NoCanalesEditField.Value);
        
            % Leer archivo.bin para insertar como binario en la BD
            fid = fopen('archivo.bin', 'r');
            binaryData = fread(fid, '*uint8');
            fclose(fid);
            
            
            fig = app.ConsultasUIFigure;
            message = 'Esto puede tardar varios minutos...';
            uialert(fig ,message,'Aviso','Icon','warning');

            %Reemplazar . por :
            tinicio = strrep(tinicio,'.',':');
            finicio = strrep(finicio,'.',':');
            
            conn = app.Conexion;
     
            nuevo = {id_paciente, finicio, tinicio, duracions, frecuencia, canales, 0x1A};      %Limite para pruebas
            datawrite = cell2table(nuevo); %Convertimos la informacion a una tabla
            datawrite.Properties.VariableNames = ["id_paciente", "fecha_prueba", "hora_inicio", "duracion", "frecuencia", "no_canales", "prueba"];
            %Nuevo formato para trabajar sqlwrite

            sqlwrite(conn, 'humana.pruebas', datawrite); %reemplazar insert por sqlwrite

            %Obtener el id de la última prueba ingresada
            query = 'SELECT max(id_prueba) AS ultima from humana.pruebas';
           
            data = fetch(conn,query);

            % Insertar datos en tabla de pruebas_datos
            record = app.datos;

            %id,id_prueba,canal_1,canal_2,...,canal_n

            encabezado{1,1}='id_prueba';

            for k = 1:canales
                encabezado{1,k+1}=strcat('canal_',num2str(k));
            end

            
%             for i = 1:length(record)
            for i = 1:100   %Limite para pruebas
                save{1,1}=data.ultima;
                for j= 1:canales
                    save{1,j+1}=record(j,i);
                end
                datawrite = cell2table(save);
                datawrite.Properties.VariableNames = [encabezado];
                sqlwrite(conn,'humana.pruebas_datos', datawrite); %se reemplazo el comando INSERT debido a que dejará de funcionar
            end

            refreshTabla(app);
            fig = app.ConsultasUIFigure;
            message = 'Se ha guardado correctamente';
            uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato
                        
            %Clear variables
            clear query

            %deshabilitar botón guardar
            app.GuardarButton.Enable = "off";
        end

        % Value changed function: TodoslosarchivosCheckBox
        function TodoslosarchivosCheckBoxValueChanged(app, event)
            value = app.TodoslosarchivosCheckBox.Value;
            if value==1
                app.SeleccionarnmeroCheckBox.Enable='off';
                app.SeleccionarrangoCheckBox.Enable='off';
                app.DescargarButton.Enable = "on";
            else 
                app.SeleccionarnmeroCheckBox.Enable='on';
                app.SeleccionarrangoCheckBox.Enable='on';
                app.DescargarButton.Enable = "off";
            end
        end

        % Button pushed function: DescargarButton
        function DescargarButtonPushed(app, event)
            id_paciente = app.IngreseelcdigodePacienteEditField.Value;
            todos = app.TodoslosarchivosCheckBox.Value;
            rango = app.SeleccionarrangoCheckBox.Value;
            numero = app.SeleccionarnmeroCheckBox.Value;
            if isempty(id_paciente)
                fig = app.ConsultasUIFigure;
                message = 'Ingrese número de paciente';
                uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato 
            else
                conn = app.Conexion;
                
                %Set query to execute on the database
                query = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales, prueba ' ...
                    'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                    id_paciente...
                    '"'];
                
                data = fetch(conn,query);
                
                if(todos==1)
                    
                    dimension=size(data);
                    nombres = [{'id_prueba'} {'fecha_prueba'} {'hora_inicio'} {'duracion(s)'} {'frecuencia(Hz)'} {'no_canales'}];
                    info = cell(dimension(1),6);
                    header = [nombres;info];
                   
                    startingFolder2 = 'C:\Program Files\MATLAB';
                    if ~exist(startingFolder2, 'dir')
                        startingFolder2 = pwd;
                    end
                    % Put in the name of the mat file that the user wants to save.
                    % defaultFileName = fullfile(startingFolder, '*.mat')
                    [file2,path2] = uiputfile('Pruebas.txt','Seleccione ruta de descarga');
                    if file2 == 0
                        % User clicked the Cancel button.
                        return;
                    end
                    fig = app.ConsultasUIFigure;
                    message = 'Esto puede tardar varios minutos...';
                    uialert(fig ,message,'Aviso','Icon','warning');%Acualizacion a ultimo formato 
                    
                    for i=1:dimension(1)
                        id_prueba = sprintf('%d',data.id_prueba(i));
                        fecha = sprintf('%s',cell2mat(data.fecha_prueba(i)));
                        hora = sprintf('%s',cell2mat(data.hora_inicio(i)));
                        duracion = sprintf('%d',data.duracion(i));
                        freq = sprintf('%d',data.frecuencia(i));
                        nocanales = sprintf('%d',data.no_canales(i));
                        prueba = deserialize(data.prueba{i,1});
                        
                        info(i,:) = [{id_prueba} {fecha} {hora} {duracion} {freq} {nocanales}];
                        header(i+1,:)=info(i,:);
                        file2 = strcat('prueba',num2str(i),'.txt');
                        fullFileName2 = fullfile(path2, file2);
                        writematrix(prueba',fullFileName2,'Delimiter','tab');
                    end
                    
                    startingFolder = 'C:\Program Files\MATLAB';
                    if ~exist(startingFolder, 'dir')
                        startingFolder = pwd;
                    end
                    % Put in the name of the mat file that the user wants to save.
                    % defaultFileName = fullfile(startingFolder, '*.mat')
                    [file,path] = uiputfile('Header.txt','Seleccione ruta y nombre para la descarga');
                    if file == 0
                        % User clicked the Cancel button.
                        return;
                    end
                    fullFileName = fullfile(path, file);
                    writecell(header,fullFileName,'Delimiter','tab');
                    fig = app.ConsultasUIFigure;
                    message = 'Se han descargado correctamente';
                    uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato
                elseif (rango==1)
                    liminf = app.EditField.Value;
                    limsup = app.EditField_2.Value;
                    if(limsup<liminf)
                        
                        fig = app.ConsultasUIFigure;
                        message = 'Límite inferior supera al límite superior. Por favor, revise el rango.';
                        uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato 
                        return;
                    else
                        nombres = [{'id_prueba'} {'fecha_prueba'} {'hora_inicio'} {'duracion(s)'} {'frecuencia(Hz)'} {'no_canales'}];
                        info = cell(length(liminf:limsup),6);
                        header = [nombres;info];
                        
                        startingFolder2 = 'C:\Program Files\MATLAB';
                        if ~exist(startingFolder2, 'dir')
                            startingFolder2 = pwd;
                        end
                        % Put in the name of the mat file that the user wants to save.
                        % defaultFileName = fullfile(startingFolder, '*.mat')
                        [file2,path2] = uiputfile('*.txt','Seleccione ruta de descarga');
                        if file2 == 0
                            % User clicked the Cancel button.
                            return;
                        end

                        fig = app.ConsultasUIFigure;
                        message = 'Esto puede tardar varios minutos...';
                        uialert(fig ,message,'Aviso','Icon','warning');%Acualizacion a ultimo formato 
                        
                        for i=liminf:limsup
                            id_prueba = sprintf('%d',data.id_prueba(i));
                            fecha = sprintf('%s',cell2mat(data.fecha_prueba(i)));
                            hora = sprintf('%s',cell2mat(data.hora_inicio(i)));
                            duracion = sprintf('%d',data.duracion(i));
                            freq = sprintf('%d',data.frecuencia(i));
                            nocanales = sprintf('%d',data.no_canales(i));
                            prueba = deserialize(data.prueba{i,1});
                            
                            info(i,:) = [{id_prueba} {fecha} {hora} {duracion} {freq} {nocanales}];
                            header(i+1,:)=info(i,:);
                            file2 = strcat('prueba',num2str(i),'.txt');
                            fullFileName2 = fullfile(path2, file2);
                            writematrix(prueba',fullFileName2,'Delimiter','tab');
                        end
                        
                        startingFolder = 'C:\Program Files\MATLAB';
                        if ~exist(startingFolder, 'dir')
                            startingFolder = pwd;
                        end
                        % Put in the name of the mat file that the user wants to save.
                        % defaultFileName = fullfile(startingFolder, '*.mat')
                        [file,path] = uiputfile('Header.txt','Seleccione ruta y nombre de descarga');
                        if file == 0
                            % User clicked the Cancel button.
                            return;
                        end
                        fullFileName = fullfile(path, file);
                        writecell(header,fullFileName,'Delimiter','tab');
                        fig = app.ConsultasUIFigure;
                        message = 'Se han descargado correctamente'; %Acualizacion a ultimo formato
                        uialert(fig ,message,'Aviso','Icon','success');
                        
                    end
                elseif (numero==1)
                    value = app.EditField_3.Value;
                    id_prueba = sprintf('%d',data.id_prueba(value));
                    fecha = sprintf('%s',cell2mat(data.fecha_prueba(value)));
                    hora = sprintf('%s',cell2mat(data.hora_inicio(value)));
                    duracion = sprintf('%d',data.duracion(value));
                    freq = sprintf('%d',data.frecuencia(value));
                    canales = sprintf('%d',data.no_canales(value));
                    %save file
                   
                    startingFolder = 'C:\Program Files\MATLAB';
                    if ~exist(startingFolder, 'dir')
                        startingFolder = pwd;
                    end
                    % Put in the name of the mat file that the user wants to save.
                    % defaultFileName = fullfile(startingFolder, '*.mat')
                    [file,path] = uiputfile('Header.txt','Seleccione ruta y nombre de descarga');
                    if file == 0
                        % User clicked the Cancel button.
                        return;
                    end
                    info = [{id_prueba} {fecha} {hora} {duracion} {freq} {canales}];
                    nombres = [{'id_prueba'} {'fecha_prueba'} {'hora_inicio'} {'duracion(s)'} {'frecuencia(Hz)'} {'no_canales'}];
                    header = [nombres;info];
                    fullFileName = fullfile(path, file);
                    writecell(header,fullFileName,'Delimiter','tab');
                    %save file
                    
                    startingFolder2 = 'C:\Program Files\MATLAB';
                    if ~exist(startingFolder2, 'dir')
                        startingFolder2 = pwd;
                    end
                    % Put in the name of the mat file that the user wants to save.
                    % defaultFileName = fullfile(startingFolder, '*.mat')
                    [file2,path2] = uiputfile('Prueba.txt','Seleccione ruta y nombre de descarga');
                    if file2 == 0
                        % User clicked the Cancel button.
                        return;
                    end
                    fullFileName2 = fullfile(path2, file2);
                    
                    fig = app.ConsultasUIFigure;
                    message = 'Esto puede tardar unos minutos...';
                    uialert(fig ,message,'Aviso','Icon','warning');%Acualizacion a ultimo formato

                    prueba = deserialize(data.prueba{value,1});
                    writematrix(prueba',fullFileName2,'Delimiter','tab');
                    
                    fig = app.ConsultasUIFigure;
                    message = 'Se han descargado correctamente';
                    uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato
                end
                
                %Clear variables
                clear query
            end
        end

        % Value changed function: DatosconfidencialesButton
        function DatosconfidencialesButtonValueChanged(app, event)
            value = app.DatosconfidencialesButton.Value;
            if value == 1
                app.SexoEditField.Visible = 'off';
                app.SexoEditFieldLabel.Visible = 'off';
                app.FechadeNacimientoEditField.Visible = 'off';
                app.FechadeNacimientoEditFieldLabel.Visible = 'off';
                app.AntecedentespersonalespatolgicosTextArea.Visible = 'off';
                app.AntecedentespersonalespatolgicosTextAreaLabel.Visible = 'off';
                app.DiagnsticodepreescripcinTextArea.Visible = 'off';
                app.DiagnsticodepreescripcinTextAreaLabel.Visible = 'off';
                app.CondicinEditField.Visible = 'off';
                app.CondicinEditFieldLabel.Visible = 'off';
                
                app.NombreEditField.Visible = 'on';
                app.NombreEditFieldLabel.Visible = 'on';
                app.ApellidoEditField.Visible = 'on';
                app.ApellidoEditFieldLabel.Visible = 'on';
                app.NotasconfidencialesTextArea.Visible = 'on';
                app.NotasconfidencialesTextAreaLabel.Visible = 'on';
            else
                app.SexoEditField.Visible = 'on';
                app.SexoEditFieldLabel.Visible = 'on';
                app.FechadeNacimientoEditField.Visible = 'on';
                app.FechadeNacimientoEditFieldLabel.Visible = 'on';
                app.AntecedentespersonalespatolgicosTextArea.Visible = 'on';
                app.AntecedentespersonalespatolgicosTextAreaLabel.Visible = 'on';
                app.DiagnsticodepreescripcinTextArea.Visible = 'on';
                app.DiagnsticodepreescripcinTextAreaLabel.Visible = 'on';
                app.CondicinEditField.Visible = 'on';
                app.CondicinEditFieldLabel.Visible = 'on';
                
                app.NombreEditField.Visible = 'off';
                app.NombreEditFieldLabel.Visible = 'off';
                app.ApellidoEditField.Visible = 'off';
                app.ApellidoEditFieldLabel.Visible = 'off';
                app.NotasconfidencialesTextArea.Visible = 'off';
                app.NotasconfidencialesTextAreaLabel.Visible = 'off';
            end
            
        end

        % Value changed function: SeleccionarrangoCheckBox
        function SeleccionarrangoCheckBoxValueChanged(app, event)
            value = app.SeleccionarrangoCheckBox.Value;
            if value==1
                app.TodoslosarchivosCheckBox.Enable='off';
                app.SeleccionarnmeroCheckBox.Enable='off';
                app.EditField.Enable = 'on';
                app.EditField_2.Enable = 'on';
                app.EditField_3.Enable = 'off';
                app.DescargarButton.Enable = "on";
            else 
                app.SeleccionarnmeroCheckBox.Enable='on';
                app.TodoslosarchivosCheckBox.Enable='on';
                app.EditField.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField_3.Enable = 'off';
                app.DescargarButton.Enable = "off";
            end
        end

        % Value changed function: SeleccionarnmeroCheckBox
        function SeleccionarnmeroCheckBoxValueChanged(app, event)
            value = app.SeleccionarnmeroCheckBox.Value;
            if value==1
                app.TodoslosarchivosCheckBox.Enable='off';
                app.SeleccionarrangoCheckBox.Enable='off';
                app.EditField.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField_3.Enable = 'on';
                app.DescargarButton.Enable = "on";
            else 
                app.TodoslosarchivosCheckBox.Enable='on';
                app.SeleccionarrangoCheckBox.Enable='on';
                app.EditField.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.EditField_3.Enable = 'off';
                app.DescargarButton.Enable = "off";
            end
        end

        % Button pushed function: BorrarpruebaButton
        function BorrarpruebaButtonPushed(app, event)
            conn = app.Conexion;
            selection = app.UITable.Selection
            fig = app.ConsultasUIFigure;
            
            if (selection(2) ~= 2 || isempty(selection)) %Indica que se debe de seleccionar en la columna prueba
              message = 'Seleccione el numero de la prueba que desea borrar en la columna "Prueba".';
              uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato

            elseif (selection(2) == 2) %Indica que los datos se borraran permanentemente
               message = '¡Precaución! Los datos serán borrados permanentemente. ¿Desea continuar?';
               
               borrar = uiconfirm(fig,message,'Aviso', ...
               'Options',{'No','Si'}, ...
               'DefaultOption',1,'CancelOption',1);
            end
            if(borrar == ('Si'))
                data = app.UITable.Data(selection(1), 2)
                id = cell2mat(data)
                query = ['DELETE FROM humana.pruebas WHERE id_prueba ="' ...
                    id ...
                    '"'];
                execute(conn,query);
                app.ActualizarButtonPushed();
            else
            end
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            if (~isempty(selection)) %Evaluamos si se selecciono un dato

                data = app.UITable.Data(selection(1), 2) %Dato seleccionado
                data = cell2mat(data) %Transformacion de cell a que contiene string 

                if (selection(2) == 2) %Evaluamos si se selecciono un dato de la columna pruebas
                    app.NoPruebaEditField.Value = data;
                    data = str2num(data);
                end
            end
        end

        % Value changed function: IngreseelcdigodePacienteEditField
        function IngreseelcdigodePacienteEditFieldValueChanged(app, event)
            %Buscar solo con presionar enter
            app.BuscarButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConsultasUIFigure = uifigure('Visible', 'off');
            app.ConsultasUIFigure.Name = 'Consultas';
            app.ConsultasUIFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.ConsultasUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConsultasUIFigureCloseRequest, true);
            app.ConsultadepacientesypruebasLabel = uilabel(app.ConsultasUIFigure);
            app.ConsultadepacientesypruebasLabel.Text = 'Consulta de pacientes y pruebas';
            app.IngreseelcdigodePacienteEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.IngreseelcdigodePacienteEditFieldLabel.Text = 'Ingrese el código de Paciente';
            app.IngreseelcdigodePacienteEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.IngreseelcdigodePacienteEditField.ValueChangedFcn = createCallbackFcn(app, @IngreseelcdigodePacienteEditFieldValueChanged, true);
            app.SexoEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.SexoEditFieldLabel.Text = 'Sexo';
            app.SexoEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.SexoEditField.Editable = 'off';
            app.FechadeNacimientoEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.FechadeNacimientoEditFieldLabel.Text = 'Fecha de Nacimiento';
            app.FechadeNacimientoEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.FechadeNacimientoEditField.Editable = 'off';
            app.AntecedentespersonalespatolgicosTextAreaLabel = uilabel(app.ConsultasUIFigure);
            app.AntecedentespersonalespatolgicosTextAreaLabel.Text = 'Antecedentes personales patológicos';
            app.AntecedentespersonalespatolgicosTextArea = uitextarea(app.ConsultasUIFigure);
            app.AntecedentespersonalespatolgicosTextArea.Editable = 'off';
            app.DiagnsticodepreescripcinTextAreaLabel = uilabel(app.ConsultasUIFigure);
            app.DiagnsticodepreescripcinTextAreaLabel.Text = 'Diagnóstico de preescripción';
            app.DiagnsticodepreescripcinTextArea = uitextarea(app.ConsultasUIFigure);
            app.DiagnsticodepreescripcinTextArea.Editable = 'off';
            app.CondicinEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.CondicinEditFieldLabel.Text = 'Condición';
            app.CondicinEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.CondicinEditField.Editable = 'off';
            app.UITable = uitable(app.ConsultasUIFigure);
            app.UITable.ColumnName = {'No.'; 'Prueba'; 'Fecha'; 'Hora'; 'Duración (s)'; 'Frecuencia (Hz)'; 'No. Canales'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.AgregarnuevoarchivoPanel = uipanel(app.ConsultasUIFigure);
            app.AgregarnuevoarchivoPanel.Title = 'Agregar nuevo archivo';
            app.HoradeinicioEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.HoradeinicioEditFieldLabel.Text = 'Hora de inicio';
            app.HoradeinicioEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.HoradeinicioEditField.Editable = 'off';
            app.DuracinsEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.DuracinsEditFieldLabel.Text = 'Duración (s)';
            app.DuracinsEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.DuracinsEditField.Editable = 'off';
            app.SeleccionarButton = uibutton(app.AgregarnuevoarchivoPanel, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.GuardarButton = uibutton(app.AgregarnuevoarchivoPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Enable = 'off';
            app.GuardarButton.Text = 'Guardar';
            app.FechadelexamenEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.FechadelexamenEditFieldLabel.Text = 'Fecha del examen';
            app.FechadelexamenEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.FechadelexamenEditField.Editable = 'off';
            app.FrecuenciaHzEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.FrecuenciaHzEditFieldLabel.Text = 'Frecuencia (Hz)';
            app.FrecuenciaHzEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.FrecuenciaHzEditField.Editable = 'off';
            app.NoCanalesEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.NoCanalesEditFieldLabel.Text = 'No. Canales';
            app.NoCanalesEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.NoCanalesEditField.Editable = 'off';
            app.BuscarButton = uibutton(app.ConsultasUIFigure, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.NuevaconsultaButton = uibutton(app.ConsultasUIFigure, 'push');
            app.NuevaconsultaButton.ButtonPushedFcn = createCallbackFcn(app, @NuevaconsultaButtonPushed, true);
            app.NuevaconsultaButton.Text = 'Nueva consulta';
            app.RegresarButton = uibutton(app.ConsultasUIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.ActualizarButton = uibutton(app.ConsultasUIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.DescargarpruebasPanel = uipanel(app.ConsultasUIFigure);
            app.DescargarpruebasPanel.Title = 'Descargar pruebas';
            app.DescargarButton = uibutton(app.DescargarpruebasPanel, 'push');
            app.DescargarButton.ButtonPushedFcn = createCallbackFcn(app, @DescargarButtonPushed, true);
            app.DescargarButton.Enable = 'off';
            app.DescargarButton.Text = 'Descargar';
            app.TodoslosarchivosCheckBox = uicheckbox(app.DescargarpruebasPanel);
            app.TodoslosarchivosCheckBox.ValueChangedFcn = createCallbackFcn(app, @TodoslosarchivosCheckBoxValueChanged, true);
            app.TodoslosarchivosCheckBox.Text = 'Todos los archivos';
            app.EditField = uieditfield(app.DescargarpruebasPanel, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.Enable = 'off';
            app.EditField.Value = 1;
            app.Label = uilabel(app.DescargarpruebasPanel);
            app.Label.Text = '-';
            app.EditField_2 = uieditfield(app.DescargarpruebasPanel, 'numeric');
            app.EditField_2.Limits = [2 Inf];
            app.EditField_2.Enable = 'off';
            app.EditField_2.Value = 2;
            app.SeleccionarrangoCheckBox = uicheckbox(app.DescargarpruebasPanel);
            app.SeleccionarrangoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeleccionarrangoCheckBoxValueChanged, true);
            app.SeleccionarrangoCheckBox.Text = 'Seleccionar rango';
            app.SeleccionarnmeroCheckBox = uicheckbox(app.DescargarpruebasPanel);
            app.SeleccionarnmeroCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeleccionarnmeroCheckBoxValueChanged, true);
            app.SeleccionarnmeroCheckBox.Text = 'Seleccionar número';
            app.EditField_3 = uieditfield(app.DescargarpruebasPanel, 'numeric');
            app.EditField_3.Limits = [1 Inf];
            app.EditField_3.Enable = 'off';
            app.EditField_3.Value = 1;
            app.InfLabel = uilabel(app.DescargarpruebasPanel);
            app.InfLabel.Text = 'Inf';
            app.SupLabel = uilabel(app.DescargarpruebasPanel);
            app.SupLabel.Text = 'Sup';
            app.NombreEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.NombreEditFieldLabel.Visible = 'off';
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.NombreEditField.Editable = 'off';
            app.NombreEditField.Visible = 'off';
            app.ApellidoEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.ApellidoEditFieldLabel.Visible = 'off';
            app.ApellidoEditFieldLabel.Text = 'Apellido';
            app.ApellidoEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.ApellidoEditField.Editable = 'off';
            app.ApellidoEditField.Visible = 'off';
            app.NotasconfidencialesTextAreaLabel = uilabel(app.ConsultasUIFigure);
            app.NotasconfidencialesTextAreaLabel.Visible = 'off';
            app.NotasconfidencialesTextAreaLabel.Text = 'Notas confidenciales';
            app.NotasconfidencialesTextArea = uitextarea(app.ConsultasUIFigure);
            app.NotasconfidencialesTextArea.Editable = 'off';
            app.NotasconfidencialesTextArea.Visible = 'off';
            app.DatosconfidencialesButton = uibutton(app.ConsultasUIFigure, 'state');
            app.DatosconfidencialesButton.ValueChangedFcn = createCallbackFcn(app, @DatosconfidencialesButtonValueChanged, true);
            app.DatosconfidencialesButton.Enable = 'off';
            app.DatosconfidencialesButton.Text = 'Datos confidenciales';
            app.BorrarpruebaButton = uibutton(app.ConsultasUIFigure, 'push');
            app.BorrarpruebaButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarpruebaButtonPushed, true);
            app.BorrarpruebaButton.Text = 'Borrar prueba';
            app.NoPruebaEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.NoPruebaEditFieldLabel.Text = 'No. Prueba';
            app.NoPruebaEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.NoPruebaEditField.InputType = 'digits';
            app.NoPruebaEditField.Editable = 'off';
            % Show the figure after all components are created
            app.ConsultasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = consultas(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConsultasUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConsultasUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConsultasUIFigure)
        end
    end
end