classdef procesamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Carac                     matlab.ui.Figure
        CargarArchivoMATButton    matlab.ui.control.Button
        RegresarButton            matlab.ui.control.Button
        SeleccionarEEGdeBasededatosButton  matlab.ui.control.Button
        EditField_2               matlab.ui.control.NumericEditField
        sLabel                    matlab.ui.control.Label
        CalcularButton            matlab.ui.control.Button
        CaractersticasTpicasButtonGroup  matlab.ui.container.ButtonGroup
        CurtosisButton            matlab.ui.control.RadioButton
        DesviacinestndarButton    matlab.ui.control.RadioButton
        PicosAmplitudMxButton     matlab.ui.control.RadioButton
        CrucesporCeroButton       matlab.ui.control.RadioButton
        MAVButton                 matlab.ui.control.RadioButton
        MediaButton               matlab.ui.control.RadioButton
        MuestrasporventanaLabel   matlab.ui.control.Label
        ExtraerVectordeCaractersticasButton  matlab.ui.control.Button
        TiempoMaxsEditField       matlab.ui.control.NumericEditField
        TiempoMaxsEditFieldLabel  matlab.ui.control.Label
        TiempoMinsEditField       matlab.ui.control.NumericEditField
        TiempoMinsEditFieldLabel  matlab.ui.control.Label
        VisualizarButton          matlab.ui.control.Button
        CanalSpinner              matlab.ui.control.Spinner
        CanalSpinnerLabel         matlab.ui.control.Label
        CargarEDFButton           matlab.ui.control.Button
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %Variables compartidas entre ventanas
        Minvalue 
        Maxvalue
        tiempo
        datos
        indexsup
        indexinf
        header
        freq
        Matriz_features
        channel_ventana
        c
        op
    end
    
    properties (Access = private)
        %Objetos GUI
        DialogApp 
        PruebaApp
        Conexion
        CallingApp
    end
    
    
    methods (Access = public)
      function features_data(app,data,hdr)
            app.datos = data;
            app.header = hdr;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            features_data(app,app.datos,app.header);
            app.ExtraerVectordeCaractersticasButton.Enable = 'off';
            app.CalcularButton.Enable = 'off';
            app.VisualizarButton.Enable = 'off';
            %Establecer coneccion con ventana de base de datos
            app.CallingApp = mainapp;
            app.Conexion = con;
            if (isempty(app.Conexion)||~isopen(app.Conexion))
                app.SeleccionarEEGdeBasededatosButton.Enable = 'off';
            else
                app.SeleccionarEEGdeBasededatosButton.Enable = 'on';
            end
        end

        % Button pushed function: CargarEDFButton
        function CargarEDFButtonPushed(app, event)
            %Abrir archivo EDF
            [file,path,~] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                disp('Usuario canceló la operacion');
            else
                disp(['EL usuario seleccionó ', fullfile(path,file)]);
            end
            [hdr, record] = edfread(strcat(path,file));
            app.datos = record;
            app.header = hdr;
            app.freq = hdr.frequency(1);
            app.VisualizarButton.Enable = 'on';
            app.ExtraerVectordeCaractersticasButton.Enable ='on';
            fig = app.Carac;
            message = 'Prueba cargada satisfactoriamente'; %Aviso nuevo
            uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato 
        end

        % Button pushed function: VisualizarButton
        function VisualizarButtonPushed(app, event)
            %ventana= app.EditField_2.Value;
            value = app.CanalSpinner.Value;
            record = app.datos;
            app.tiempo = 1:length(record);
            tmin = 256*(app.TiempoMinsEditField.Value);
            tmax = 256*(app.TiempoMaxsEditField.Value);
            %Acotar gráfica según tamaño del archivo EDF
            if tmax>length(record)
                tmax = length(record);
                app.TiempoMaxsEditField.Value = round(length(record)/256);
            elseif mod(tmax,1)~=0 || mod(tmin,1)~=0 %si es decimal
                tmax = round(tmax);
                tmin = round(tmin);
            elseif tmin==0
                app.TiempoMinsEditField.Value = 1;
                tmin=1;
            end
            app.UIAxes.XLim=[tmin, tmax];
            app.indexinf = tmin;
            app.indexsup = tmax;
            %Graficar canal y tiempo seleccionado
            switch value
                case 1
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 2
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 3
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 4
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 5
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 6
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 7
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 8
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 9
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 10
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 11
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 12
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 13
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 14
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 15
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 16
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 17
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 18
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 19
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 20
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 21
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 22
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 23
                    plot(app.UIAxes,app.tiempo,record(value,:))
            end
            app.CalcularButton.Enable = 'on';
            %app.UIAxes_2.cla; %(CP)
            cla(app.UIAxes_2);

        end

        % Button pushed function: ExtraerVectordeCaractersticasButton
        function ExtraerVectordeCaractersticasButtonPushed(app, event)
             % Abrir ventana de caracteristicas
            app.DialogApp = features(app);
        end

        % Close request function: Carac
        function CaracCloseRequest(app, event)
        if (isempty(app.Conexion)||~isopen(app.Conexion))
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'off';
            app.CallingApp.ConsultarButton.Enable = 'off';
            app.CallingApp.AnalizarpruebaButton.Enable = 'on';
            app.CallingApp.DesconectarButton.Enable = 'off';
        else
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
            app.CallingApp.ConsultarButton.Enable = 'on';
            app.CallingApp.AnalizarpruebaButton.Enable = 'on';
            app.CallingApp.DesconectarButton.Enable = 'on';
        end
            delete(app)
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
           %app.UIAxes_2.cla; %(CP)
           cla(app.UIAxes_2);
           if ((app.TiempoMaxsEditField.Value-app.TiempoMinsEditField.Value)>=60)
                %Mensaje de advertencia 
                message = sprintf('Esta operación puede tomar algunos segundos');
                uialert(app.Carac,message,'Aviso',...
                'Icon','warning');
           end
       
            %Calcular características típicas en tiempo real
            canal = app.CanalSpinner.Value;
            muestras = app.EditField_2.Value;
            signal = app.datos;
            app.op = [0,0,0,0,0,0];
            app.indexinf = 256*(app.TiempoMinsEditField.Value);
            app.indexsup = 256*(app.TiempoMaxsEditField.Value);
            %Acotar tamaño de ventana según gráfica
            if app.indexinf==0
                app.indexinf=1;
            end
            if mod(app.indexinf*256/2,1)~=0 || mod(app.indexsup*256/2,1)~=0  %si es decimal 
                app.indexsup = round( app.indexsup);
                app.indexinf = round(app.indexinf);
            end
            if app.MediaButton.Value
                app.op(1)=1;
            elseif app.MAVButton.Value
                app.op(2)=1;     
            elseif app.CrucesporCeroButton.Value
                app.op(3)=1;
            elseif app.PicosAmplitudMxButton.Value
                app.op(4)=1;
            elseif app.DesviacinestndarButton.Value
                app.op(5)=1;
            elseif app.CurtosisButton.Value
                app.op(6)=1;
            end
            %Utilizar funcion features_tipicas
            [vector,~] = Features_tipicas(signal(canal,app.indexinf:app.indexsup),app.freq,1,muestras,canal,app.op);
            if length(vector)==1
                app.UIAxes_2.XLim=[0, length(vector)];
            else
                app.UIAxes_2.XLim=[1, length(vector)];
            end
            
            %Desplegar grafica
            plot(app.UIAxes_2,1:length(vector),vector)
            
        end

        % Button pushed function: SeleccionarEEGdeBasededatosButton
        function SeleccionarEEGdeBasededatosButtonPushed(app, event)
            app.PruebaApp = selectprueba(app, app.Conexion);
            app.ExtraerVectordeCaractersticasButton.Enable = 'on';
           
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            if (isempty(app.Conexion)||~isopen(app.Conexion))
                app.CallingApp.AgregarnuevopacienteButton.Enable = 'off';
                app.CallingApp.ConsultarButton.Enable = 'off';
                app.CallingApp.AnalizarpruebaButton.Enable = 'on';
                app.CallingApp.DesconectarButton.Enable = 'off';
            else
                app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
                app.CallingApp.ConsultarButton.Enable = 'on';
                app.CallingApp.AnalizarpruebaButton.Enable = 'on';
                app.CallingApp.DesconectarButton.Enable = 'on';
            end
            delete(app)
        end

        % Button pushed function: CargarArchivoMATButton
        function CargarArchivoMATButtonPushed(app, event)
            %Seleccionar archivo MAT
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo M');
            if isequal(file,0)
                disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
            end
            %Asegurarse que el workspace cargado contenga la variable data
            workspace = load(strcat(path,file),'data');
            app.datos =  workspace.data;
            %Asegurarse que la señal este muestreada a 256Hz o cambiar
            %variable freq
            app.freq = 256;
            app.VisualizarButton.Enable = 'on';
            app.ExtraerVectordeCaractersticasButton.Enable ='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Carac = uifigure('Visible', 'off');
            app.Carac.Name = 'Visualización de Señal';
            app.Carac.CloseRequestFcn = createCallbackFcn(app, @CaracCloseRequest, true);
            app.UIAxes = uiaxes(app.Carac);
            title(app.UIAxes, 'Señal EEG')
            xlabel(app.UIAxes, 'Muestras')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes_2 = uiaxes(app.Carac);
            xlabel(app.UIAxes_2, 'Ventanas')
            ylabel(app.UIAxes_2, 'Característica')
            app.CargarEDFButton = uibutton(app.Carac, 'push');
            app.CargarEDFButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEDFButtonPushed, true);
            app.CargarEDFButton.Text = 'Cargar EDF';
            app.CanalSpinnerLabel = uilabel(app.Carac);
            app.CanalSpinnerLabel.Text = 'Canal';
            app.CanalSpinner = uispinner(app.Carac);
            app.CanalSpinner.Limits = [1 23];
            app.CanalSpinner.Value = 1;
            app.VisualizarButton = uibutton(app.Carac, 'push');
            app.VisualizarButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarButtonPushed, true);
            app.VisualizarButton.Text = 'Visualizar';
            app.TiempoMinsEditFieldLabel = uilabel(app.Carac);
            app.TiempoMinsEditFieldLabel.Text = 'Tiempo Min (s)';
            app.TiempoMinsEditField = uieditfield(app.Carac, 'numeric');
            app.TiempoMaxsEditFieldLabel = uilabel(app.Carac);
            app.TiempoMaxsEditFieldLabel.Text = 'Tiempo Max (s)';
            app.TiempoMaxsEditField = uieditfield(app.Carac, 'numeric');
            app.TiempoMaxsEditField.Value = 10;
            app.ExtraerVectordeCaractersticasButton = uibutton(app.Carac, 'push');
            app.ExtraerVectordeCaractersticasButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraerVectordeCaractersticasButtonPushed, true);
            app.ExtraerVectordeCaractersticasButton.Text = 'Extraer Vector de Características';
            app.MuestrasporventanaLabel = uilabel(app.Carac);
            app.MuestrasporventanaLabel.Text = 'Muestras por ventana';
            app.CaractersticasTpicasButtonGroup = uibuttongroup(app.Carac);
            app.CaractersticasTpicasButtonGroup.Title = 'Características Típicas';
            app.MediaButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.MediaButton.Text = 'Media';
            app.MediaButton.Value = true;
            app.MAVButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.MAVButton.Text = 'MAV';
            app.CrucesporCeroButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.CrucesporCeroButton.Text = 'Cruces por Cero';
            app.PicosAmplitudMxButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.PicosAmplitudMxButton.Text = 'Picos/Amplitud Máx.';
            app.DesviacinestndarButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.DesviacinestndarButton.Text = 'Desviación estándar';
            app.CurtosisButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.CurtosisButton.Text = 'Curtosis';
            app.CalcularButton = uibutton(app.Carac, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.sLabel = uilabel(app.Carac);
            app.sLabel.Text = '(256 = 1s)';
            app.EditField_2 = uieditfield(app.Carac, 'numeric');
            app.EditField_2.Value = 256;
            app.SeleccionarEEGdeBasededatosButton = uibutton(app.Carac, 'push');
            app.SeleccionarEEGdeBasededatosButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarEEGdeBasededatosButtonPushed, true);
            app.SeleccionarEEGdeBasededatosButton.Text = 'Seleccionar EEG de Base de datos';
            app.RegresarButton = uibutton(app.Carac, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.CargarArchivoMATButton = uibutton(app.Carac, 'push');
            app.CargarArchivoMATButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoMATButtonPushed, true);
            app.CargarArchivoMATButton.Text = 'Cargar Archivo MAT';
            % Show the figure after all components are created
            app.Carac.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = procesamiento(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Carac)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Carac)
        end
    end
end